classdef QMU_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        Label_4              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        REditField           matlab.ui.control.EditField
        mKnobLabel           matlab.ui.control.Label
        RKnob                matlab.ui.control.Knob
        cmLabel              matlab.ui.control.Label
        HKnob                matlab.ui.control.Knob
        MPaKnobLabel         matlab.ui.control.Label
        MPaKnob              matlab.ui.control.Knob
        ThetaKnobLabel       matlab.ui.control.Label
        ThetaKnob            matlab.ui.control.Knob
        mKnob_2Label         matlab.ui.control.Label
        R2Knob               matlab.ui.control.Knob
        HEditField           matlab.ui.control.EditField
        R2EditField          matlab.ui.control.EditField
        ThetaEditField       matlab.ui.control.EditField
        MPaEditField         matlab.ui.control.EditField
        MLPredictButton      matlab.ui.control.Button
        ZJLamp               matlab.ui.control.Lamp
        MCLamp               matlab.ui.control.Lamp
        Label_6              matlab.ui.control.Label
        ZJEditField          matlab.ui.control.EditField
        Label_7              matlab.ui.control.Label
        MCEditField          matlab.ui.control.EditField
        QMUPanel             matlab.ui.container.Panel
        ZJButton             matlab.ui.control.Button
        ZJUITable            matlab.ui.control.Table
        MCFilePathEditField  matlab.ui.control.EditField
        MCUITable            matlab.ui.control.Table
        MCButton             matlab.ui.control.Button
        FilePathLabel        matlab.ui.control.Label
        ZJFilePathEditField  matlab.ui.control.EditField
        MEditFieldLabel      matlab.ui.control.Label
        MEditField           matlab.ui.control.EditField
        Label_16             matlab.ui.control.Label
        MCbetweenEditField   matlab.ui.control.EditField
        Label_18             matlab.ui.control.Label
        P0EditField          matlab.ui.control.EditField
        bEditField_2Label    matlab.ui.control.Label
        bEditField           matlab.ui.control.EditField
        Label_19             matlab.ui.control.Label
        rouEditField         matlab.ui.control.EditField
        GEditField_2Label    matlab.ui.control.Label
        GEditField           matlab.ui.control.EditField
        HEditField_2Label    matlab.ui.control.Label
        H2EditField          matlab.ui.control.EditField
        Label_20             matlab.ui.control.Label
        UpEditField          matlab.ui.control.EditField
        Label_22             matlab.ui.control.Label
        MCalphaEditField     matlab.ui.control.EditField
        X0Label              matlab.ui.control.Label
        X0EditField          matlab.ui.control.EditField
        XthEditFieldLabel    matlab.ui.control.Label
        XthEditField         matlab.ui.control.EditField
        MLabel               matlab.ui.control.Label
        ZJMEditField         matlab.ui.control.EditField
        QLabel               matlab.ui.control.Label
        ZJQEditField         matlab.ui.control.EditField
        UEditFieldLabel      matlab.ui.control.Label
        ZJUEditField         matlab.ui.control.EditField
        Label_12             matlab.ui.control.Label
        X0Label_2            matlab.ui.control.Label
        MClimEditField       matlab.ui.control.EditField
        XthEditField_2Label  matlab.ui.control.Label
        Xth2EditField        matlab.ui.control.EditField
        MLabel_2             matlab.ui.control.Label
        MCMEditField         matlab.ui.control.EditField
        ULabel_2             matlab.ui.control.Label
        MCUEditField         matlab.ui.control.EditField
        QLabel_2             matlab.ui.control.Label
        MCQEditField         matlab.ui.control.EditField
        Label_11             matlab.ui.control.Label
        QMUButton            matlab.ui.control.Button
        reportButton         matlab.ui.control.Button
        QLabel_3             matlab.ui.control.Label
        MixQEditField        matlab.ui.control.EditField
        MLabel_3             matlab.ui.control.Label
        MixMEditField        matlab.ui.control.EditField
        ULabel_3             matlab.ui.control.Label
        MixUEditField        matlab.ui.control.EditField
        QMULabel_5           matlab.ui.control.Label
        ZJGauge              matlab.ui.control.Gauge
        ZJQLamp              matlab.ui.control.Lamp
        MCGauge              matlab.ui.control.Gauge
        MCQLamp              matlab.ui.control.Lamp
        Label_23             matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        ResetButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        UIZJAxes             matlab.ui.control.UIAxes
        UIMCAxes             matlab.ui.control.UIAxes
        Button               matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        % 全局属性存放于此
        
%         % 机器学习预测生成结果
%         zj_predict;
%         mc_predict;
%         mix_predict;
        
%         mc_th;
        
        % QMU评估
        xls_dir;
        xls_dir2;
        
        data_example;
        Ni_Stimulus_Xi;
        Stimulus_Xi;
        stage_mitrix_i;
        nABMb;
        parameter_n;
        parameter_A;
        parameter_B;
        parameter_M;
        parameter_little_b;
        parameter_rou;
        
        mc_data;
        data_col;
        data_example2_matrix;
        zhixindu;
        explore_num;
        mc_prob;
        mc_Z;
        f_a;
        f_b;
        f_c;
        f_delta;
        mc_plow;
        mc_phigh;
        mc_between;
        
        % 生成结果
        ZJ_M;
        ZJ_U;
        ZJ_Q;
        MC_M;
        MC_U;
        MC_Q;
        
        mixQ;   
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MLPredictButton
        function MLPredictButtonPushed(app, event)
            % 机器学习预测
           % 按下预测按钮             
           
            % 撞击数据预测
%             load zj_model.mat;

%             load zj_model_new.mat; % 3.11新模型
            exampleObject = matfile('zj_model_new.mat');
            zjmodel= exampleObject.trainedModel;
%             varlist = who(exampleObject);

%             zj_table = table(200, 20);  %测试数据R=200,H=20
            zj_table = table(app.RKnob.Value, app.HKnob.Value);
            zj_table.Properties.VariableNames{'Var1'} = 'VarName1';
            zj_table.Properties.VariableNames{'Var2'} = 'VarName2';
%             zj_predict = trainedModel.predictFcn(zj_table);    %3.11
            zj_predict = zjmodel.predictFcn(zj_table); %4.19


            % 摩擦数据预测
%             load mc_model.mat;
%             load ('mc_model.mat');
            exampleObject = matfile('mc_model.mat');
            mcmodel= exampleObject.trainedModel;
            
%             mc_table = table(200, 270, 90);  %测试数据R=200,压力270,摆角30
            mc_table = table(app.R2Knob.Value, app.MPaKnob.Value, app.ThetaKnob.Value);
            mc_table.Properties.VariableNames{'Var1'} = 'VarName1';
            mc_table.Properties.VariableNames{'Var2'} = 'VarName2';
            mc_table.Properties.VariableNames{'Var3'} = 'VarName3';
            % mc_predict = trainedClassifier.predictFcn(mc_table);
            
%             mc_predict = trainedModel.predictFcn(mc_table); %3.11
            mc_predict = mcmodel.predictFcn(mc_table); %4.19
          
            %% 融合预测概率
%             alpha = app.alphaKnob.Value; %设置撞击概率权重
%             mix_predict = alpha * zj_predict + (1-alpha) * mc_predict;
      
            % 异常判定
%             if ((app.REditField.Value<=0) || (app.PEditField.Value<=0) || (app.ThetaEditField.Value<=0) ...
%                     || (app.PEditField.Value<=0) ||  (app.AlphaEditField.Value<0) || (app.AlphaEditField.Value>1) )
%             s = '数据不足或异常';
%             app.ZJEditField.Value = num2str(s);
%             app.MCEditField.Value = num2str(s);
%             app.MixEditField.Value = num2str(s);
%             
%             else
            % 预测结果打印
            app.ZJEditField.Value = num2str(zj_predict);
            app.MCEditField.Value = num2str(mc_predict);
%             app.MixEditField.Value = num2str(mix_predict);
            
%             end
            % 灯光显示,响应即红灯
            if zj_predict == 1
                set(app.ZJLamp, 'Color', [1,0,0]) 
            else
                set(app.ZJLamp, 'Color', [0,1,0])
            end
            
            if mc_predict == 1
                set(app.MCLamp, 'Color', [1,0,0]) 
            else
                set(app.MCLamp, 'Color', [0,1,0])
            end
            
%             if mix_predict >=0.5
%                 set(app.MixLamp, 'Color', [1,0,0]) 
%             else
%                 set(app.MixLamp, 'Color', [0,1,0])
%             end

            
        end

        % Value changed function: RKnob
        function RKnobValueChanged(app, event)
%             value = app.RKnob.Value;
            app.REditField.Value = num2str(app.RKnob.Value);
        end

        % Value changed function: HKnob
        function HKnobValueChanged(app, event)
%             value = app.HKnob.Value;
            app.HEditField.Value = num2str(app.HKnob.Value);
        end

        % Callback function
        function alphaKnobValueChanged(app, event)
%             value = app.alphaKnob.Value;
            app.alphaEditField.Value = num2str(app.alphaKnob.Value);
        end

        % Value changed function: R2Knob
        function R2KnobValueChanged(app, event)
%             value = app.R2Knob.Value;
            app.R2EditField.Value = num2str(app.R2Knob.Value);
        end

        % Value changed function: ThetaKnob
        function ThetaKnobValueChanged(app, event)
%             value = app.ThetaKnob.Value;
            app.ThetaEditField.Value = num2str(app.ThetaKnob.Value);
        end

        % Value changed function: MPaKnob
        function MPaKnobValueChanged(app, event)
%             value = app.MPaKnob.Value;
            app.MPaEditField.Value = num2str(app.MPaKnob.Value);
        end

        % Button pushed function: ZJButton
        function ZJButtonPushed(app, event)
            % 导入撞击表格数据
            % 按下导入撞击数据按钮
            
%             输入excel的相对或绝对路径
            app.xls_dir = app.ZJFilePathEditField.Value;
            app.data_example = xlsread(app.xls_dir, 'sheet1');

            % 比较v和m大小。判定n取vi还是mi
            [app.Ni_Stimulus_Xi,~] = get_Ni_Stimulus_Xi(app.data_example);
            
            app.Stimulus_Xi = app.data_example(:,1); %读取刺激量xi
            
            % 获取中位数X0 以及台阶数矩阵,步长d
            [app.stage_mitrix_i,~,~] =...
                get_stage_mitrix_and_X0(app.Stimulus_Xi);
            
            % 计算A B M b 的模块
            app.nABMb = calculate_parameter_n_A_B_M_b(app.Ni_Stimulus_Xi,app.stage_mitrix_i);
            app.parameter_n = app.nABMb.parameter_n;
            app.parameter_A = app.nABMb.parameter_A;
            app.parameter_B = app.nABMb.parameter_B;
            app.parameter_M = app.nABMb.parameter_M;
            app.parameter_little_b = app.nABMb.parameter_little_b;      
            
%             %数据有效性判定 1有效 0 无效
%             data_validity = check_for_data_validity(parameter_M);
%             if data_validity == 1
%                 s = '数据有效，请查表输入G,H';
%             else 
%                 s = '数据无效，M<0.25，请重新导入';
%             end
%             % 显示有效性结果
%             app.VaildEditField.Value = s;
            
            % 表格数据显示到界面上
            app.ZJUITable.Data = app.data_example;
            
%---------------------------------------------------------------------            
            
            % 计算ρ 
            app.parameter_M = roundn(app.parameter_M, -2); %保留两位小数
            if (app.parameter_M > 0.3)
                app.parameter_rou = 1.62 * ( app.parameter_M + 0.029 );
            else
%                 s = 'M<0.3, 请查表ρ(M,b)，自行输入ρ值！';
%                 app.NoteEditField.Value = s;
                % 自行给app.rouEditField.Value赋值
                app.parameter_rou = app.rouEditField.Value;
            end

            % 显示计算数据
            app.MEditField.Value = num2str(app.parameter_M);
            app.bEditField.Value = num2str(app.parameter_little_b);
            app.rouEditField.Value = num2str(app.parameter_rou);
            

        end

        % Button pushed function: MCButton
        function MCButtonPushed(app, event)
           % 按下导入摩擦数据按钮
            
%             输入excel的相对或绝对路径
            app.xls_dir2 = app.MCFilePathEditField.Value;
            app.mc_data = xlsread(app.xls_dir2, 'sheet1');
            
            app.data_col = length(app.mc_data)/5;
            app.data_example2_matrix = reshape(app.mc_data, app.data_col, 5 ); %美化界面，五列展示
            
              % 表格数据显示到界面上
            app.MCUITable.Data = app.data_example2_matrix;
            
%             app.MCNoteEditField.Value = '置信水平,摩擦安全设计值需自行输入';
%---------------------------------------------------------------------  

            %相关参数设置
            app.zhixindu = str2double(app.MCalphaEditField.Value);
%             app.mc_th = str2double(app.MClimEditField.Value);
%             zhixindu = 0.95; %置信度
%             mc_th = 0.55; %安全性设计值

            % 计算试验数据的摩擦感度p
            app.explore_num = length(find(app.mc_data == 1));
            app.mc_prob = app.explore_num/length(app.mc_data);

            % 估计p的置信区间
            % 本质是一元二次方程
            app.mc_Z = get_Up((1-app.zhixindu) /2);% Za/2
            app.f_a = length(app.mc_data) +  app.mc_Z^2;
            app.f_b = -(2*length(app.mc_data)*app.mc_prob + app.mc_Z^2);
            app.f_c = length(app.mc_data)* (app.mc_prob^2);
            app.f_delta = sqrt(app.f_b^2 - 4*app.f_a*app.f_c);
            app.mc_plow = (-app.f_b - app.f_delta) / (2*app.f_a); %p2
            app.mc_phigh = (-app.f_b + app.f_delta) / (2*app.f_a); %p1
            app.mc_between = [app.mc_plow, app.mc_phigh];
            
            % 显示p和区间 
            app.P0EditField.Value = num2str(app.mc_prob);
            app.MCbetweenEditField.Value = num2str(app.mc_between);
            
            
        end

        % Button pushed function: QMUButton
        function QMUButtonPushed(app, event)
            % 按下QMU评估按钮 计算QMU
            % 撞击Q
%             data_example = xlsread(app.xls_dir, 'sheet1');
% 
            [~,explode_or_unexplode_data_flag] = get_Ni_Stimulus_Xi(app.data_example);
%             
%             Stimulus_Xi = data_example(:,1); %读取刺激量xi
%             
            % 获取中位数X0 以及台阶数矩阵,步长d
            [~,Stimulus_median_X0,parameter_Step_sized] =...
                get_stage_mitrix_and_X0(app.Stimulus_Xi);
%             
%             % 计算A B M b 的模块
%             nABMb = calculate_parameter_n_A_B_M_b(Ni_Stimulus_Xi,stage_mitrix_i);
%             parameter_n = nABMb.parameter_n;
%             parameter_A = nABMb.parameter_A;
%             parameter_B = nABMb.parameter_B;
%             parameter_M = nABMb.parameter_M;
%             parameter_little_b = nABMb.parameter_little_b;      
%             
%           
%             % 计算ρ 
%             parameter_M = roundn(parameter_M, -2); %保留两位小数
%             if (parameter_M > 0.3)
%                 parameter_rou = 1.62 * ( parameter_M + 0.029 );
%             else
% %                 s = 'M<0.3, 请查表ρ(M,b)，自行输入ρ值！';
% %                 app.MCNoteEditField.Value = s;
%                 % 自行给app.rouEditField.Value赋值
%                 parameter_rou = app.rouEditField.Value;
%             end

            % 显示计算数据
%             app.MEditField.Value = num2str(parameter_M);
%             app.bEditField.Value = num2str(parameter_little_b);
%             app.rouEditField.Value = num2str(parameter_rou);
            
            % 计算mu-hat
            parameter_miu = calculate_parameter_miu(...
                Stimulus_median_X0,explode_or_unexplode_data_flag,...
                app.parameter_A, app.parameter_n, parameter_Step_sized);            
            
            % 计算sigma-hat
            Std_Dev_sigma = calculate_Std_Dev_sigma(app.parameter_rou,parameter_Step_sized);  
         
            % 由自行输入的G H 
            % 计算sigma_mean sigma_variance
            parameter_G = str2double(app.GEditField.Value);
            parameter_H = str2double(app.H2EditField.Value);
            sigma_mean = parameter_G * Std_Dev_sigma / sqrt(app.parameter_n);
            sigma_variance = parameter_H * Std_Dev_sigma / sqrt(app.parameter_n);

            % 由自行输入的置信水平
            prob = str2double(app.UpEditField.Value);
            % 计算sigma_Xp
            mu = 0;
            sigma = 1;
            pd = makedist('Normal','mu',mu,'sigma',sigma);
            p = 1 - prob;
            Up = icdf(pd,p);
%             p = 0.0001;
            sigma_explode_prob_Xp = sqrt(sigma_mean^2 + (Up * sigma_variance)^2 );
         
            % 计算置信区间（上下限）
%             explode_prob_Xp_QuantileU = ...
%                 calculate_explode_prob_Xp_QuantileU(explode_prob_Xp,Confidence_level,sigma_explode_prob_Xp);
            %撞击感度取置信下限
            % 计算M=Xth-X0
            X_th = parameter_miu - abs(Up) * Std_Dev_sigma;

%             X_zero = str2double(app.X0EditField.Value);
            % 显示安全性设计值X0，若满足不等式大于1，X0小于X_pl即可
            
            X_pl = X_th - abs(Up) * sigma_explode_prob_Xp;     
            
            if app.DropDown.Value == char('自动计算')
                X0 = X_pl-0.1;
            else
                X0 = str2double(app.X0EditField.Value);  % 可以自定义修改安全性设计值
            end
         
            app.ZJ_M = X_th - X0;
            app.ZJ_U = X_th - X_pl; 
            % 计算Q
            app.ZJ_Q = app.ZJ_M ./ app.ZJ_U;
            
            % 显示计算数据和指示灯（Q值小于为红灯）
            app.XthEditField.Value = num2str(X_th);
            app.X0EditField.Value = num2str(X0);
            app.ZJMEditField.Value = num2str(app.ZJ_M);
            app.ZJUEditField.Value = num2str(app.ZJ_U);
            app.ZJQEditField.Value = num2str(app.ZJ_Q);
            while app.ZJ_Q<=1
                set(app.ZJQLamp, 'Color', [1,0,0])
            end
   
            % 显示至仪表
            app.ZJGauge.Value = app.ZJ_Q;
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
            % 摩擦Q
            % 按下导入摩擦数据按钮
            
%             mc_data = xlsread(app.xls_dir2, 'sheet1');
%             
%             %相关参数设置
%             zhixindu = str2double(app.MCalphaEditField.Value);
%             app.mc_th = str2double(app.MClimEditField.Value);
% % app.mc_th = str2double(app.testEditField.Value);
%             
% %             zhixindu = 0.95; %置信度
% %             mc_th = 0.956; %安全性设计值
%             
%             % 计算试验数据的摩擦感度p
%             explore_num = length(find(mc_data == 1));
%             mc_prob = explore_num/length(mc_data);
%             
%             % 估计p的置信区间
%             % 本质是一元二次方程
%             mc_Z = get_Up((1-zhixindu) /2);% Za/2
%             f_a = length(mc_data) +  mc_Z^2;
%             f_b = -(2*length(mc_data)*mc_prob + mc_Z^2);
%             f_c = length(mc_data)* (mc_prob^2);
%             f_delta = sqrt(f_b^2 - 4*f_a*f_c);
%             mc_plow = (-f_b - f_delta) / (2*f_a); %p1
%             mc_phigh = (-f_b + f_delta) / (2*f_a); %p2
%             mc_between = [mc_plow, mc_phigh];
%             
%             % 显示p和区间 
%             app.P0EditField.Value = num2str(mc_prob);
%             app.MCbetweenEditField.Value = num2str(mc_between);
            
            % B类不确定度评定
            % 计算sigma_miu
            prob_a = (app.mc_phigh - app.mc_plow) / 2;
            mc_v = length(app.mc_data)-1;
            mc_t = tinv(1-((1-app.zhixindu) /2), mc_v); %计算t值
            mc_ux = prob_a / mc_t;
            % 计算sigma_sigma
            mc_sigmasigma = mc_ux/sqrt(2* mc_v);
            % 计算合成sigma_plow
            mc_sigmaplow = sqrt(mc_ux^2 + (mc_t*mc_sigmasigma)^2);


%             % 上限版本
%             % 若满足不等式大于1， mc_th > mc_phigh + mc_sigmaplow 即可
%             mc_th = app.mc_phigh + mc_sigmaplow + 0.01 ;
%             
%             app.MC_M = mc_th - app.mc_phigh;
%             
%             % 计算U
%             % MC_U = abs(get_Up(zhixindu)) * mc_sigmaplow;
%             app.MC_U = mc_sigmaplow;
            
            % 下限版本
            % 若满足不等式大于1， mc_th < mc_plow - mc_sigmaplow 即可
            
%             swith app.DropDown.Value
%                 case '计算生成'        
%                     mc_th = app.mc_plow - mc_sigmaplow - 0.01;    
%                 otherwise             
%                     mc_th = str2double(app.MClimEditField.Value); % 可以自定义修改安全性设计值
%                     
%             end
            
            if app.DropDown.Value == char('自动计算')
                mc_th = app.mc_plow - mc_sigmaplow - 0.01;
            else
                mc_th = str2double(app.MClimEditField.Value); % 可以自定义修改安全性设计值
            end
            
            app.MC_M = app.mc_plow - mc_th;
                
            % 计算U
            % MC_U = abs(get_Up(zhixindu)) * mc_sigmaplow;
            app.MC_U = mc_sigmaplow;


            % 计算Q
            app.MC_Q = app.MC_M ./ app.MC_U;
            
%             显示计算数据
            app.Xth2EditField.Value = num2str(app.mc_plow); %Xth
            app.MClimEditField.Value = num2str(mc_th); %X0  
            app.MCMEditField.Value = num2str(app.MC_M);
            app.MCUEditField.Value = num2str(app.MC_U);
            app.MCQEditField.Value = num2str(app.MC_Q);
            while app.MC_Q<=1
                set(app.MCQLamp, 'Color', [1,0,0])
            end         

            % 显示至仪表
            app.MCGauge.Value = app.MC_Q;

%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   

            % 多元QMU评估Q值            
            mixM = sqrt((X_th-X0)^2 + (app.mc_plow-mc_th)^2);
            mixU = sqrt((X_th-X_pl)^2 + (mc_sigmaplow)^2 );
            
            app.mixQ = mixM ./ mixU;
            
            app.MixMEditField.Value = num2str(mixM);
            app.MixUEditField.Value = num2str(mixU);
            app.MixQEditField.Value = num2str(app.mixQ);
            
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   

            % 显示Q值的图像
            ax = app.UIAxes;
            ax.XLim = [0 3];
            ax.YLim = [0 3];
%             x = [0 1 1 0];
%             y = [0 0 1 1];
%             x = [1 3 3 1];
%             y = [1 1 3 3];
            x = [0 3 3 1 1 0];
            y = [0 0 1 1 3 3];

            patch(ax, x, y, 'red');
            hold(ax, 'on');
            plot(ax, app.ZJ_Q, app.MC_Q, 'd',...
                'MarkerFaceColor', 'g',...
                'MarkerSize',10);
            
            % 显示撞击QMU图像
            
            axzj = app.UIZJAxes;
            
            axzj.YLim = [X0-0.2 X_th+0.2];
            xx = [1 2];
            x1 = plot(axzj, xx, [X_th X_th],'r:');
            hold(axzj, 'on');
            x2 = plot(axzj, xx, [X_pl X_pl],'g-');
            hold(axzj, 'on');
            x3 = plot(axzj, xx, [X0 X0],'k-');
            hold(axzj, 'on');
            legend(axzj, [x1(1),x2(1),x3(1)],'临界阈值','单侧置信上限','安全设计值')
            
            % 显示摩擦QMU图像
            axmc = app.UIMCAxes;
            
            axmc.YLim = [mc_th-0.1, app.mc_plow+0.1];
            x4 = plot(axmc, xx, [app.mc_plow app.mc_plow],'r:');
            hold(axmc, 'on');
            x5 = plot(axmc, xx, [app.mc_plow-mc_sigmaplow app.mc_plow-mc_sigmaplow],'g-');
            hold(axmc, 'on');
            x6 = plot(axmc, xx, [mc_th mc_th],'k-');           
            hold(axmc, 'on');
            legend(axmc, [x4(1),x5(1),x6(1)],'临界阈值','单侧置信上限','安全设计值')
            
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
%---------------------------------------------------------------------   
            % 显示雷达图
            D1 = [app.ZJ_Q, app.MC_Q, 0, 0, 0];
            D2 = [1, 1, 0, 0, 0];          
            lim = [0,3;0,3;0,3;0,3;0,3];
            labels = {'撞击', '摩擦', '未定义', '未定义', '未定义'};
%            legendlables = {'test','threshold'};
            Draw_radar(D1,lim,labels);
            hold on;
            Draw_radar(D2,lim,labels);            
            
%----------------------------以下为测试内容------------------------
%             % 更新至2022.2.22 待修改
%             % 测试是否可以在app上画雷达图 不生成 新的界面
%             
%             % function Draw_radar(data,lim,labels)
%             %data是要画图的数据（根据数据的个数来确定雷达图的轴数）
%             %lim是各指标画图上下限范围
%             %labels是坐标轴名称
%             data = [ZJ_Q, MC_Q, 0, 0, 0]; 
%             lim = [0,3;0,3;0,3;0,3;0,3];
%             labels = {'撞击', '摩擦', '未定义', '未定义', '未定义'};
%             
%             ax2 = app.UIZJAxes;
%             
%             n=length(data);%维度
%             adj_data=zeros(n,1);
%             point=zeros(n,2);
%             
%             set(gca,'units','normal','pos',[0 0 1 1]);
% %             axis off
% %             axis equal
% %             hold on
% 
%             
% 
%             theta_last=pi/2;
%             for i=1:n
%                 theta=2*pi/n*i+pi/2;
%                 plot(ax2, [0,500*cos(theta)],[0,500*sin(theta)],'k-','linewidth',2);
%                 for j=1:5
%                    plot(ax2, [j*100*cos(theta_last),j*100*cos(theta)],[j*100*sin(theta_last),j*100*sin(theta)],'--','linewidth',0.75,'color',[0.5,0.5,0.5]);
%                 end
%                 
%                 theta_last=theta;
%                 if data(i)<lim(i,1)
%                     adj_data(i)=0;
%                 elseif data(i)>lim(i,2)
%                     adj_data(i)=500;
%                 else
%                     adj_data(i)=(data(i)-lim(i,1))/(lim(i,2)-lim(i,1))*500;
%                 end
%                 point(i,1:2)=[adj_data(i)*cos(theta);adj_data(i)*sin(theta)];
%                 text_around(510*cos(theta),510*sin(theta),labels{i},theta);
%             end
%             
%             for i=1:n
%                 theta=2*pi/n*i+pi/2;
%                 for j=1:5
%                     text_around(j*100*cos(theta),j*100*sin(theta),num2str(lim(i,1)+(lim(i,2)-lim(i,1))/5*j),theta+pi/2,7);
%                 end
%             end
%             plot(ax2, [point(:,1);point(1,1)],[point(:,2);point(1,2)],'k-','linewidth',0.8);
%             fill(point(:,1),point(:,2),[0.9 0.9 0.7])
%             alpha(0.5);
%             texts=findobj(gca,'Type','Text');
%             minx=-300;
%             maxx=300;
%             miny=-300;
%             maxy=300;
%             for i=1:length(texts)
%                 rect=get(texts(i),'Extent');
%                 x=rect(1);
%                 y=rect(2);
%                 dx=rect(3);
%                 dy=rect(4);
%                 if x<minx
%                     minx=x;
%                 elseif x+dx>maxx
%                     maxx=x+dx;
%                 end
%                 if y<miny
%                     miny=y;
%                 elseif y+dy>maxy
%                     maxy=y+dy;
%                 end
%             end
%             axis([minx-50,maxx+50,miny-20,maxy+20]);
%                  
%             
%             hold(ax2,'on');
%             
%             
%             
%             
%             
%             D2 = [1, 1, 0, 0, 0]; 
% %             lim = [0,3;0,3;0,3;0,3;0,3];
% %             labels = {'撞击', '摩擦', '未定义', '未定义', '未定义'};
%             
%             ax2 = app.UIZJAxes;
%             
%             n=length(D2);%维度
%             adj_data=zeros(n,1);
%             point=zeros(n,2);
%             
%             set(gca,'units','normal','pos',[0 0 1 1]);
% %             axis off
% %             axis equal
% %             hold on
% 
%             theta_last=pi/2;
%             for i=1:n
%                 theta=2*pi/n*i+pi/2;
%                 plot(ax2, [0,500*cos(theta)],[0,500*sin(theta)],'k-','linewidth',2);
%                 for j=1:5
%                    plot(ax2, [j*100*cos(theta_last),j*100*cos(theta)],[j*100*sin(theta_last),j*100*sin(theta)],'--','linewidth',0.75,'color',[0.5,0.5,0.5]);
%                 end
%                 
%                 theta_last=theta;
%                 if D2(i)<lim(i,1)
%                     adj_data(i)=0;
%                 elseif D2(i)>lim(i,2)
%                     adj_data(i)=500;
%                 else
%                     adj_data(i)=(D2(i)-lim(i,1))/(lim(i,2)-lim(i,1))*500;
%                 end
%                 point(i,1:2)=[adj_data(i)*cos(theta);adj_data(i)*sin(theta)];
%                 text_around(510*cos(theta),510*sin(theta),labels{i},theta);
%             end
%             
%             for i=1:n
%                 theta=2*pi/n*i+pi/2;
%                 for j=1:5
%                     text_around(j*100*cos(theta),j*100*sin(theta),num2str(lim(i,1)+(lim(i,2)-lim(i,1))/5*j),theta+pi/2,7);
%                 end
%             end
%             plot(ax2, [point(:,1);point(1,1)],[point(:,2);point(1,2)],'k-','linewidth',0.8);
%             fill(point(:,1),point(:,2),[0.9 0.9 0.7])
%             alpha(0.5);
%             texts=findobj(gca,'Type','Text');
%             minx=-300;
%             maxx=300;
%             miny=-300;
%             maxy=300;
%             for i=1:length(texts)
%                 rect=get(texts(i),'Extent');
%                 x=rect(1);
%                 y=rect(2);
%                 dx=rect(3);
%                 dy=rect(4);
%                 if x<minx
%                     minx=x;
%                 elseif x+dx>maxx
%                     maxx=x+dx;
%                 end
%                 if y<miny
%                     miny=y;
%                 elseif y+dy>maxy
%                     maxy=y+dy;
%                 end
%             end
%             axis([minx-50,maxx+50,miny-20,maxy+20]);
            
            
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
%             value = app.REditField.Value;
            app.RKnob.Value = str2double(app.REditField.Value);

        end

        % Value changed function: HEditField
        function HEditFieldValueChanged(app, event)
            app.HKnob.Value = str2double(app.HEditField.Value);
            
        end

        % Callback function
        function alphaEditFieldValueChanged(app, event)
            app.alphaKnob.Value = str2double(app.alphaEditField.Value);
            
        end

        % Value changed function: R2EditField
        function R2EditFieldValueChanged(app, event)
            app.R2Knob.Value = str2double(app.R2EditField.Value);
            
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.ThetaKnob.Value = str2double(app.ThetaEditField.Value);
            
        end

        % Value changed function: MPaEditField
        function MPaEditFieldValueChanged(app, event)
            app.MPaKnob.Value = str2double(app.MPaEditField.Value);
            
        end

        % Button pushed function: reportButton
        function reportButtonPushed(app, event)
            % 打印评估结果
            
            % 3.8 第一版，简陋版
            % 4.20 更新 第二版
            if (app.ZJ_Q>1) && (app.MC_Q>1)
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：满足可靠性要求'}, ...
                '评估结果判定', ...
                opts);
            elseif (app.ZJ_Q>1) && (app.MC_Q<1) && (app.mixQ>1)
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：满足可靠性要求'}, ...
                '评估结果判定', ...
                'error', ...
                opts);                
           elseif (app.ZJ_Q>1) && (app.MC_Q<1) && (app.mixQ<1)
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：不满足可靠性要求'}, ...
                '评估结果判定', ...
                'error', ...
                opts);     
            elseif (app.ZJ_Q<1) && (app.MC_Q>1) && (app.mixQ>1)
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：满足可靠性要求'}, ...
                '评估结果判定', ...
                'error', ...
                opts);    
            elseif (app.ZJ_Q<1) && (app.MC_Q>1) && (app.mixQ<1)
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：不满足可靠性要求'}, ...
                '评估结果判定', ...
                'error', ...
                opts);  
            else
                opts = struct('WindowStyle','modal', ...
                    'Interpreter','tex');
                f = msgbox({'\bf\fontsize{15}撞击感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}摩擦感度不满足可靠性要求'; ...
                    '\bf\fontsize{15}综合QMU评估：不满足可靠性要求'}, ...
                '评估结果判定', ...
                'error', ...
                opts);                
            end
            
% app.testEditField.Value = num2str(app.ZJ_Q);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 关闭界面
            ques = questdlg('确定要关闭吗','提示','是','否','是');
            switch ques
                case '是'
                    delete(app.UIFigure);
                    return;
                case '否'
                    return;
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %% 重置所有计算结果
%             clear all;
            cla(app.UIAxes)
            cla(app.UIMCAxes)
            cla(app.UIZJAxes)              

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '火炸药机械感度可靠性多元评估系统';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '机器学习预测响应-输入                                                                                  输出';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '撞击感度试验';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '摩擦感度试验';
            app.REditField = uieditfield(app.Panel, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.mKnobLabel = uilabel(app.Panel);
            app.mKnobLabel.Text = '颗粒直径/μm';
            app.RKnob = uiknob(app.Panel, 'continuous');
            app.RKnob.Limits = [0 500];
            app.RKnob.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.RKnob.ValueChangedFcn = createCallbackFcn(app, @RKnobValueChanged, true);
            app.RKnob.MinorTicks = [];
            app.cmLabel = uilabel(app.Panel);
            app.cmLabel.Text = {'落高/cm'; '(取对数)'};
            app.HKnob = uiknob(app.Panel, 'continuous');
            app.HKnob.Limits = [0 2];
            app.HKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.HKnob.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1', '1.2', '1.4', '1.6', '1.8', '2'};
            app.HKnob.ValueChangedFcn = createCallbackFcn(app, @HKnobValueChanged, true);
            app.HKnob.MinorTicks = [];
            app.MPaKnobLabel = uilabel(app.Panel);
            app.MPaKnobLabel.Text = '压力/MPa';
            app.MPaKnob = uiknob(app.Panel, 'continuous');
            app.MPaKnob.Limits = [0 400];
            app.MPaKnob.ValueChangedFcn = createCallbackFcn(app, @MPaKnobValueChanged, true);
            app.ThetaKnobLabel = uilabel(app.Panel);
            app.ThetaKnobLabel.Text = '摆角/Theta';
            app.ThetaKnob = uiknob(app.Panel, 'continuous');
            app.ThetaKnob.Limits = [0 90];
            app.ThetaKnob.MajorTicks = [0 15 30 45 60 75 90];
            app.ThetaKnob.ValueChangedFcn = createCallbackFcn(app, @ThetaKnobValueChanged, true);
            app.mKnob_2Label = uilabel(app.Panel);
            app.mKnob_2Label.Text = '颗粒直径/μm';
            app.R2Knob = uiknob(app.Panel, 'continuous');
            app.R2Knob.Limits = [0 500];
            app.R2Knob.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.R2Knob.ValueChangedFcn = createCallbackFcn(app, @R2KnobValueChanged, true);
            app.R2Knob.MinorTicks = [];
            app.HEditField = uieditfield(app.Panel, 'text');
            app.HEditField.ValueChangedFcn = createCallbackFcn(app, @HEditFieldValueChanged, true);
            app.R2EditField = uieditfield(app.Panel, 'text');
            app.R2EditField.ValueChangedFcn = createCallbackFcn(app, @R2EditFieldValueChanged, true);
            app.ThetaEditField = uieditfield(app.Panel, 'text');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.MPaEditField = uieditfield(app.Panel, 'text');
            app.MPaEditField.ValueChangedFcn = createCallbackFcn(app, @MPaEditFieldValueChanged, true);
            app.MLPredictButton = uibutton(app.Panel, 'push');
            app.MLPredictButton.ButtonPushedFcn = createCallbackFcn(app, @MLPredictButtonPushed, true);
            app.MLPredictButton.Text = '预测响应值';
            app.ZJLamp = uilamp(app.Panel);
            app.MCLamp = uilamp(app.Panel);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '撞击响应';
            app.ZJEditField = uieditfield(app.Panel, 'text');
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '摩擦响应';
            app.MCEditField = uieditfield(app.Panel, 'text');
            app.QMUPanel = uipanel(app.UIFigure);
            app.QMUPanel.Title = 'QMU评估可靠性-输入                                            输出';
            app.ZJButton = uibutton(app.QMUPanel, 'push');
            app.ZJButton.ButtonPushedFcn = createCallbackFcn(app, @ZJButtonPushed, true);
            app.ZJButton.Tooltip = {'选择撞击数据Excel表格路径'};
            app.ZJButton.Text = '导入撞击数据';
            app.ZJUITable = uitable(app.QMUPanel);
            app.ZJUITable.ColumnName = {'Xi'; 'Vi'; 'Mi'; 'Sum_Vi_Mi'};
            app.ZJUITable.RowName = {};
            app.MCFilePathEditField = uieditfield(app.QMUPanel, 'text');
            app.MCFilePathEditField.Tooltip = {'输入摩擦试验excel文件的完整路径'};
            app.MCFilePathEditField.Value = '.\mc_example.xlsx';
            app.MCUITable = uitable(app.QMUPanel);
            app.MCUITable.ColumnName = {'响应结果'; ''; ''; ''};
            app.MCUITable.RowName = {};
            app.MCButton = uibutton(app.QMUPanel, 'push');
            app.MCButton.ButtonPushedFcn = createCallbackFcn(app, @MCButtonPushed, true);
            app.MCButton.Tooltip = {'选择摩擦数据Excel表格路径'};
            app.MCButton.Text = '导入摩擦数据';
            app.FilePathLabel = uilabel(app.QMUPanel);
            app.FilePathLabel.Text = 'File Path';
            app.ZJFilePathEditField = uieditfield(app.QMUPanel, 'text');
            app.ZJFilePathEditField.Tooltip = {'输入撞击试验excel文件的完整路径'};
            app.ZJFilePathEditField.Value = '.\zj_example.xlsx';
            app.MEditFieldLabel = uilabel(app.QMUPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.QMUPanel, 'text');
            app.Label_16 = uilabel(app.QMUPanel);
            app.Label_16.Text = '置信区间';
            app.MCbetweenEditField = uieditfield(app.QMUPanel, 'text');
            app.Label_18 = uilabel(app.QMUPanel);
            app.Label_18.Text = '爆炸概率';
            app.P0EditField = uieditfield(app.QMUPanel, 'text');
            app.bEditField_2Label = uilabel(app.QMUPanel);
            app.bEditField_2Label.Text = 'b';
            app.bEditField = uieditfield(app.QMUPanel, 'text');
            app.Label_19 = uilabel(app.QMUPanel);
            app.Label_19.Text = 'ρ';
            app.rouEditField = uieditfield(app.QMUPanel, 'text');
            app.rouEditField.Tooltip = {'ρ可计算或查表得出'};
            app.GEditField_2Label = uilabel(app.QMUPanel);
            app.GEditField_2Label.Text = 'G';
            app.GEditField = uieditfield(app.QMUPanel, 'text');
            app.GEditField.Tooltip = {'查表输入G值'};
            app.GEditField.Value = '1.031';
            app.HEditField_2Label = uilabel(app.QMUPanel);
            app.HEditField_2Label.Text = 'H';
            app.H2EditField = uieditfield(app.QMUPanel, 'text');
            app.H2EditField.Tooltip = {'查表输入H值'};
            app.H2EditField.Value = '1.325';
            app.Label_20 = uilabel(app.QMUPanel);
            app.Label_20.Text = '置信水平';
            app.UpEditField = uieditfield(app.QMUPanel, 'text');
            app.UpEditField.Value = '0.9999';
            app.Label_22 = uilabel(app.QMUPanel);
            app.Label_22.Text = '置信水平';
            app.MCalphaEditField = uieditfield(app.QMUPanel, 'text');
            app.MCalphaEditField.Value = '0.95';
            app.X0Label = uilabel(app.QMUPanel);
            app.X0Label.Text = '安全设计值X0';
            app.X0EditField = uieditfield(app.QMUPanel, 'text');
            app.XthEditFieldLabel = uilabel(app.QMUPanel);
            app.XthEditFieldLabel.Text = '临界阈值Xth';
            app.XthEditField = uieditfield(app.QMUPanel, 'text');
            app.MLabel = uilabel(app.QMUPanel);
            app.MLabel.Text = 'M';
            app.ZJMEditField = uieditfield(app.QMUPanel, 'text');
            app.QLabel = uilabel(app.QMUPanel);
            app.QLabel.Text = 'Q';
            app.ZJQEditField = uieditfield(app.QMUPanel, 'text');
            app.UEditFieldLabel = uilabel(app.QMUPanel);
            app.UEditFieldLabel.Text = 'U';
            app.ZJUEditField = uieditfield(app.QMUPanel, 'text');
            app.Label_12 = uilabel(app.QMUPanel);
            app.Label_12.Text = '摩擦试验';
            app.X0Label_2 = uilabel(app.QMUPanel);
            app.X0Label_2.Text = '安全设计值X0';
            app.MClimEditField = uieditfield(app.QMUPanel, 'text');
            app.XthEditField_2Label = uilabel(app.QMUPanel);
            app.XthEditField_2Label.Text = '临界阈值Xth';
            app.Xth2EditField = uieditfield(app.QMUPanel, 'text');
            app.MLabel_2 = uilabel(app.QMUPanel);
            app.MLabel_2.Text = 'M';
            app.MCMEditField = uieditfield(app.QMUPanel, 'text');
            app.ULabel_2 = uilabel(app.QMUPanel);
            app.ULabel_2.Text = 'U';
            app.MCUEditField = uieditfield(app.QMUPanel, 'text');
            app.QLabel_2 = uilabel(app.QMUPanel);
            app.QLabel_2.Text = 'Q';
            app.MCQEditField = uieditfield(app.QMUPanel, 'text');
            app.Label_11 = uilabel(app.QMUPanel);
            app.Label_11.Text = '撞击试验';
            app.QMUButton = uibutton(app.QMUPanel, 'push');
            app.QMUButton.ButtonPushedFcn = createCallbackFcn(app, @QMUButtonPushed, true);
            app.QMUButton.Text = 'QMU评估';
            app.reportButton = uibutton(app.QMUPanel, 'push');
            app.reportButton.ButtonPushedFcn = createCallbackFcn(app, @reportButtonPushed, true);
            app.reportButton.Text = '评估结果';
            app.QLabel_3 = uilabel(app.QMUPanel);
            app.QLabel_3.Text = 'Q';
            app.MixQEditField = uieditfield(app.QMUPanel, 'text');
            app.MLabel_3 = uilabel(app.QMUPanel);
            app.MLabel_3.Text = 'M';
            app.MixMEditField = uieditfield(app.QMUPanel, 'text');
            app.ULabel_3 = uilabel(app.QMUPanel);
            app.ULabel_3.Text = 'U';
            app.MixUEditField = uieditfield(app.QMUPanel, 'text');
            app.QMULabel_5 = uilabel(app.QMUPanel);
            app.QMULabel_5.Text = '多元QMU评估';
            app.ZJGauge = uigauge(app.QMUPanel, 'circular');
            app.ZJGauge.Limits = [0 3];
            app.ZJQLamp = uilamp(app.QMUPanel);
            app.MCGauge = uigauge(app.QMUPanel, 'circular');
            app.MCGauge.Limits = [0 3];
            app.MCQLamp = uilamp(app.QMUPanel);
            app.Label_23 = uilabel(app.QMUPanel);
            app.Label_23.Text = '安全性设计值输入方式';
            app.DropDown = uidropdown(app.QMUPanel);
            app.DropDown.Items = {'自动计算', '手动输入'};
            app.DropDown.Value = '自动计算';
            app.ResetButton = uibutton(app.QMUPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '重置';
            app.UIAxes = uiaxes(app.QMUPanel);
            title(app.UIAxes, 'QMU综合评估')
            xlabel(app.UIAxes, '撞击')
            ylabel(app.UIAxes, '摩擦')
            app.UIAxes.XTick = [0 1 2 3];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'};
            app.UIAxes.YTick = [0 1 2 3];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'};
            app.UIZJAxes = uiaxes(app.QMUPanel);
            title(app.UIZJAxes, '撞击QMU结果')
            app.UIZJAxes.XTick = [];
            app.UIZJAxes.YTick = [];
            app.UIMCAxes = uiaxes(app.QMUPanel);
            title(app.UIMCAxes, '摩擦QMU结果')
            app.UIMCAxes.XTick = [];
            app.UIMCAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QMU_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end