classdef predict_zj_mc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PredictButton        matlab.ui.control.Button
        Label                matlab.ui.control.Label
        ZJEditField          matlab.ui.control.EditField
        Label_3              matlab.ui.control.Label
        MCEditField          matlab.ui.control.EditField
        mEditFieldLabel      matlab.ui.control.Label
        REditField           matlab.ui.control.NumericEditField
        cmEditFieldLabel     matlab.ui.control.Label
        HEditField           matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        MPaEditFieldLabel    matlab.ui.control.Label
        PEditField           matlab.ui.control.NumericEditField
        ThetaEditFieldLabel  matlab.ui.control.Label
        ThetaEditField       matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        MixEditField         matlab.ui.control.EditField
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%         xls_dir = app.FilePathEditField.Value; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % 按下预测按钮
           
            % 撞击数据预测
            load zj_model.mat;
%             zj_table = table(200, 20);  %测试数据R=200,H=20
            zj_table = table(app.REditField.Value, app.HEditField.Value);
            zj_table.Properties.VariableNames{'Var1'} = 'VarName1';
            zj_table.Properties.VariableNames{'Var2'} = 'VarName2';
            zj_predict = trainedModel1.predictFcn(zj_table);     

            % 摩擦数据预测
            load mc_model.mat;
%             mc_table = table(200, 270, 90);  %测试数据R=200,压力270,摆角30
            mc_table = table(app.REditField.Value, app.PEditField.Value, app.ThetaEditField.Value);
            mc_table.Properties.VariableNames{'Var1'} = 'VarName1';
            mc_table.Properties.VariableNames{'Var2'} = 'VarName2';
            mc_table.Properties.VariableNames{'Var3'} = 'VarName3';
            % mc_predict = trainedClassifier.predictFcn(mc_table);
            mc_predict = trainedModel.predictFcn(mc_table);
          
            %% 融合预测概率
            alpha = app.AlphaEditField.Value; %设置撞击概率权重
            mix_predict = alpha * zj_predict + (1-alpha) * mc_predict;
      
            % 异常判定
            if ((app.REditField.Value<=0) || (app.PEditField.Value<=0) || (app.ThetaEditField.Value<=0) ...
                    || (app.PEditField.Value<=0) ||  (app.AlphaEditField.Value<0) || (app.AlphaEditField.Value>1) )
            s = '数据不足或异常';
            app.ZJEditField.Value = num2str(s);
            app.MCEditField.Value = num2str(s);
            app.MixEditField.Value = num2str(s);
            
            else
            % 预测结果打印
            app.ZJEditField.Value = num2str(zj_predict);
            app.MCEditField.Value = num2str(mc_predict);
            app.MixEditField.Value = num2str(mix_predict);

            % 融合预测结果可视化
            ax = app.UIAxes;
%             ax.XLim = [0 1];
%             ax.YLim = [0 1];
            plot(ax, [0, 1], [mc_predict, mc_predict], 'b'); 
            hold(ax,'on');
            plot(ax, [zj_predict, zj_predict], [0, 1],'r');
            legend(ax, '摩擦', '撞击');
            
            
            ax2 = app.UIAxes2;
            ax2.YLim = [-0.1 1.1];
            plot(ax2,[0, 10],[mix_predict, mix_predict], 'r'); 
            legend(ax2,'融合');
            
            
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = '预测';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '撞击响应值';
            app.ZJEditField = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '摩擦响应值';
            app.MCEditField = uieditfield(app.UIFigure, 'text');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '颗粒直径/μm';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Value = 200;
            app.cmEditFieldLabel = uilabel(app.UIFigure);
            app.cmEditFieldLabel.Text = '落高/cm';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.Value = 50;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '请输入实验数据：';
            app.MPaEditFieldLabel = uilabel(app.UIFigure);
            app.MPaEditFieldLabel.Text = '压力/MPa';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Value = 88;
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = '摆角/Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.Value = 90;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '自定义撞击权重';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.Tooltip = {'范围在0-1之间'};
            app.AlphaEditField.Value = 0.5;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '融合预测响应值';
            app.MixEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '预测结果')
            xlabel(app.UIAxes, '撞击')
            ylabel(app.UIAxes, '摩擦')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '融合预测')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = predict_zj_mc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end