classdef predict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        PredictButton           matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        FilePathEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        muEditField             matlab.ui.control.EditField
        Label_2                 matlab.ui.control.Label
        sigmaEditField          matlab.ui.control.EditField
        VaildEditField          matlab.ui.control.EditField
        XpEditFieldLabel        matlab.ui.control.Label
        XpEditField             matlab.ui.control.EditField
        QEditFieldLabel         matlab.ui.control.Label
        QEditField              matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%         xls_dir = app.FilePathEditField.Value; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 按下导入数据按钮
            
            % 输入excel的绝对路径
            xls_dir = app.FilePathEditField.Value;
            
%             xls_dir = 'E:\MATLAB\MyMatlab\QMU\BP\example.xlsx';
            data_example = xlsread(xls_dir, 'sheet1');
            
            % 表格数据显示到界面上
            app.UITable.Data = data_example;
            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %按下预测按钮
            xls_dir = app.FilePathEditField.Value;
            
            data_example = xlsread(xls_dir, 'sheet1');
            
            Stimulus_Xi = data_example(:,1); %读取刺激量xi
            
            % 比较v和m大小。判定n取vi还是mi
            [Ni_Stimulus_Xi,explode_or_unexplode_data_flag] = get_Ni_Stimulus_Xi(data_example);
            
            % 获取中位数X0 以及台阶数矩阵,步长d
            [stage_mitrix_i,Stimulus_median_X0,parameter_Step_sized] =...
                get_stage_mitrix_and_X0(Stimulus_Xi);
            
            % 计算A B M b 的模块
            nABMb = calculate_parameter_n_A_B_M_b(Ni_Stimulus_Xi,stage_mitrix_i);
            parameter_n = nABMb.parameter_n;
            parameter_A = nABMb.parameter_A;
            parameter_B = nABMb.parameter_B;
            parameter_M = nABMb.parameter_M;
            parameter_little_b = nABMb.parameter_little_b;
            
            %数据有效性判定 1有效 0 无效
            data_validity = check_for_data_validity(parameter_M);
            if data_validity == 1
                s = '数据有效';
            else 
                s = '数据无效，M《0.25';
            end
            
            % 计算mu-hat
            parameter_miu = calculate_parameter_miu(...
                Stimulus_median_X0,explode_or_unexplode_data_flag,...
                parameter_A, parameter_n, parameter_Step_sized);
             
            % 计算ρ 
            parameter_rou = calculate_parameter_rou(parameter_M,parameter_little_b);

            % 计算sigma-hat
            Std_Dev_sigma = calculate_Std_Dev_sigma(parameter_rou,parameter_Step_sized); 
            
            %显示结果至文本框
            app.muEditField.Value = num2str(parameter_miu);
            app.sigmaEditField.Value = num2str(Std_Dev_sigma);
            app.VaildEditField.Value = s;
            
            
            % 加载预训练模型
            load matlab.mat;
            
            % 输入数据预测（按列）
            predict1 = sim(net, [parameter_miu Std_Dev_sigma]');
            
            app.XpEditField.Value = num2str(predict1(1));
            app.QEditField.Value = num2str(predict1(2));
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            
            fig = uifigure;
            ax = uiaxes(fig);
%             x = linspace(-pi,pi,50);
            y = 5*sin(x);
            plot(ax,x,y)
            
            subplot(2,2,3); plot([0, n],[prob, prob],'blue'); hold on; plot([0, n],[0.5, 0.5],'red');
            xlabel('样本数'); ylabel('Xp'); ylim([0 1]); legend('试验的Xp','0.5概率线'); 
            % axis padded
            title('Xp'); 
            
            subplot(2,2,4); plot([0, n],[Q,Q], 'blue'); hold on; plot([0, n],[1,1],'red')
            xlabel('样本数'); ylabel('Q'); ylim([-1 4]); legend('试验的Q值','Q=1边界线'); 
            % axis padded
            title('Q值'); 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入数据';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = '预测';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Xi'; 'Vi'; 'Mi'; 'Sum_Vi_Mi'};
            app.UITable.RowName = {};
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Tooltip = {'在此输入excel文件的完整路径'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'μ';
            app.muEditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'σ';
            app.sigmaEditField = uieditfield(app.UIFigure, 'text');
            app.VaildEditField = uieditfield(app.UIFigure, 'text');
            app.VaildEditField.Tooltip = {'判定数据是否有效'};
            app.XpEditFieldLabel = uilabel(app.UIFigure);
            app.XpEditFieldLabel.Text = 'Xp';
            app.XpEditField = uieditfield(app.UIFigure, 'text');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = predict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end