classdef TableDataAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        Origin_data                     matlab.ui.control.Table
        FilePathEditFieldLabel          matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        Preprocess_res_table            matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        Distribution_choose             matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Client_alert                    matlab.ui.control.TextArea
        GpbEditFieldLabel               matlab.ui.control.Label
        GpbEditField                    matlab.ui.control.NumericEditField
        HpbEditFieldLabel               matlab.ui.control.Label
        HpbEditField                    matlab.ui.control.NumericEditField
        pMbEditFieldLabel               matlab.ui.control.Label
        pMbEditField                    matlab.ui.control.NumericEditField
        Button_2                        matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Explode_Prob                    matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        Trust_value                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        Design_Safety_Val               matlab.ui.control.NumericEditField
        Confrim_Prob                    matlab.ui.control.StateButton
        Result_plot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data_Whole_Structure % Description
    end

    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
           % t = app.Origin_data.DisplayData;            
            
            % Plot modified data 
%             x2 = t.Age;
%             y2 = t.BloodPressure(:,2);
%             plot(app.UIAxes2,x2,y2,'-o');
            x_miritx = [app.Data_Whole_Structure.explode_prob_Xp_upper,app.Data_Whole_Structure.explode_prob_Xp_QuantileU_upper,app.Data_Whole_Structure.design_safety_X0];
            %x_miritx = [Xp_upper,Xpu_upper,X0];
            % xlim([0 10])
            % ylim([-0.4 0.8])
            x = 1:10;
            y1 = x_miritx(1)+zeros(10,1);
            plot(app.Result_plot,x,y1,'--r'); %蓝色线
            
            hold(app.Result_plot);
            
            y2 = x_miritx(2)+zeros(10,1);
            plot(app.Result_plot,x,y2,'-b') ;%蓝色线
            
            %hold(app.Result_plot);
            
            y3 = x_miritx(3)+zeros(10,1);
            plot(app.Result_plot,x,y3,'-b'); %蓝色线
%             
%             hold(app.Result_plot);
            rymax = 1.01*max(x_miritx);
            rymin = 0.95*min(x_miritx);
            app.Result_plot.YLim = [rymin,rymax];
            app.Result_plot.XLim = [0,11];
        end
        
        
        function update_preprocess_table(app)
%             result_table = cell(10,1);
%             result_table{1,1} = app.Data_Whole_Structure.Distribution;
%             result_table{2,1} = app.Data_Whole_Structure.explode_or_unexplode_data_flag;
%             result_table{3,1} = app.Data_Whole_Structure.Stimulus_median_X0;
%             result_table{4,1} = app.Data_Whole_Structure.parameter_Step_sized;
%             result_table{5,1} = app.Data_Whole_Structure.parameter_n;
%             result_table{6,1} = app.Data_Whole_Structure.parameter_A;
%             result_table{7,1} = app.Data_Whole_Structure.parameter_B;
%             result_table{8,1} = app.Data_Whole_Structure.parameter_M;
%             result_table{9,1} = app.Data_Whole_Structure.parameter_little_b;
%             result_table{10,1} = app.Data_Whole_Structure.data_validity;
            result_table = generate_result_table(app.Data_Whole_Structure);
%             if(app.Data_Whole_Structure.parameter_rou == 0)
%                 result_table{11,1} = app.Data_Whole_Structure.parameter_rou;
%             else
%                 result_table{11,1} = '需要查表';
%             end
            app.Preprocess_res_table.Data = result_table;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
%             t = readtable('emptydatasheet.xlsx');
%             vars = {'刺激量Xi','爆炸次数','未爆炸次数','SelfAssessedHealthStatus','Smoker'};
            
            % Select a subset of the table array
            % t = t(1:20,vars);
            
            % Sort the data by age
           % t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
%             t.BloodPressure = [t.Systolic t.Diastolic];
%             t.Systolic = [];
%             t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
%             cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
%             t.SelfAssessedHealthStatus = cats;
%             
%             % Rearrange columns
%             t = t(:,[1 4 3 2]);
%             
%             % Add data to the Table UI Component
%             app.Origin_data.Data = t;
%             
%             % Plot the original data
%             x1 = app.Origin_data.Data.Age;
%             y1 = app.Origin_data.Data.BloodPressure(:,2);
%             plot(app.Result_plot,x1,y1,'o-');
%             
%             % Plot the data
%             updateplot(app);
        end

        % Display data changed function: Origin_data
        function Origin_dataDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function: Button, FilePathEditField
        function ButtonPushed(app, event)

            %             parameter_miu = calculate_parameter_miu(...
            %                 Stimulus_median_X0,explode_or_unexplode_data_flag,...
            %                 nABMb.parameter_A, nABMb.parameter_n, parameter_Step_sized);
            %
            %             data_result = [nABMb.parameter_n,nABMb.parameter_A,...
            %                 nABMb.parameter_B,nABMb.parameter_M,nABMb.parameter_little_b,parameter_miu,data_validity];
            %
            %
            %             app.Preprocess_res_table.Data = data_result;
            %
            %             [alert_String,parameter_rou] = ...
            %                 app_request_for_rou_G_H(nABMb.parameter_M,app.data_Distribution);
            %
            %             app.Client_alert.Value = alert_String;
            % 根据路径读取表格
            file_path = app.FilePathEditField.Value;
            file_read_data = xlsread(file_path);
            % 显示初始数据
            app.Origin_data.Data = file_read_data;
            % 根据下拉菜单设置分布，并执行初始化
            if(strcmp(app.Distribution_choose.Value , '正态分布或对数正态分布'))
                Distribution_value = 1;
            else
                Distribution_value = 0;
            end
            app.Data_Whole_Structure = data_parameters_structure(file_read_data,Distribution_value);
            update_preprocess_table(app);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if(~(app.Data_Whole_Structure.Distribution_value && (app.Data_Whole_Structure.parameter_M > 0.3)))
                parameter_rou = app.pMbEditField.Value;
            end
            parameter_G = app.GpbEditField.Value;
            parameter_H = app.HpbEditField.Value;
            app.Data_Whole_Structure = app.Data_Whole_Structure.set_the_rou_G_H(parameter_rou,parameter_G,parameter_H);
            update_preprocess_table(app);
            
        end

        % Value changed function: Confrim_Prob
        function Confrim_ProbValueChanged(app, event)
            value = app.Confrim_Prob.Value;
            app.Data_Whole_Structure = app.Data_Whole_Structure.set_the_prob_p(app.Explode_Prob.Value);
            app.Data_Whole_Structure.design_safety_X0 = app.Design_Safety_Val.Value;
            app.Data_Whole_Structure.Confidence_level = app.Trust_value.Value;
            app.Data_Whole_Structure = app.Data_Whole_Structure.set_orther_parameters();
            update_preprocess_table(app);
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.Origin_data = uitable(app.BloodPressureAnalyisisUIFigure);
            app.Origin_data.ColumnName = {'刺激量Xi'; '爆炸次数'; '未爆炸次数'; '实验次数'; '台阶数'};
            app.Origin_data.RowName = {};
            app.Origin_data.ColumnSortable = [true false true true];
            app.Origin_data.ColumnEditable = [true false true true];
            app.Origin_data.DisplayDataChangedFcn = createCallbackFcn(app, @Origin_dataDisplayDataChanged, true);
            app.Origin_data.Tag = 'original_data';
            app.FilePathEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.FilePathEditField.Value = './datapaper.xlsx';
            app.Button = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Preprocess_res_table = uitable(app.BloodPressureAnalyisisUIFigure);
            app.Preprocess_res_table.ColumnName = {'属性值'};
            app.Preprocess_res_table.RowName = {'数据分布'; '数据分布标签值（1为正态分布，0为逻辑斯蒂分布）'; 'Qmu_Q'; 'Qmu_M'; 'Qmu_U'; '选用爆炸或未爆炸的数据作为计算值（1为爆炸，-1为未爆）'; '刺激量中位数'; '步长'; '参数n'; '参数A'; '参数B'; '参数M'; '参数b'; '本组数据有效性（1为有效，0为无效）'; '爆炸概率或未爆炸概率'; '爆炸概率的P分位值'; '参数均值u（miu）'; '参数p（rou）p（M，b）   [一些M值和特定分布下，需要查表输入]'; '参数G            G（rou，b）[需要查表输入]'; '参数H            H（rou，b）[需要查表输入]'; '标准差'; '均值miu估计的标准误差'; '方差sigma估计的标准误差'; '置信水平（1-Alfa）'; '安全性设计值 [需要设定输入]'; '给定爆炸概率P下的响应点Xp标准误差'; '给定爆炸概率P下的响应点Xp上限'; '给定爆炸概率P下的响应点Xp下限'; '置信水平（1-Alfa）下的单侧置信上限'; '置信水平（1-Alfa）下的单侧置信下限'};
            app.Label = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.Label.Text = '数据服从分布选择';
            app.Distribution_choose = uidropdown(app.BloodPressureAnalyisisUIFigure);
            app.Distribution_choose.Items = {'正态分布或对数正态分布', '逻辑斯蒂分布或对数逻辑斯蒂分布'};
            app.Distribution_choose.Value = '正态分布或对数正态分布';
            app.Label_2 = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '用户提示区域:';
            app.Client_alert = uitextarea(app.BloodPressureAnalyisisUIFigure);
            app.Client_alert.Editable = 'off';
            app.Client_alert.Visible = 'off';
            app.Client_alert.Value = {''; ''};
            app.GpbEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GpbEditFieldLabel.Text = 'G(p,b)';
            app.GpbEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.GpbEditField.Value = 1.134;
            app.HpbEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.HpbEditFieldLabel.Text = 'H(p,b)';
            app.HpbEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.HpbEditField.Value = 1.146;
            app.pMbEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.pMbEditFieldLabel.Text = 'p(M,b)';
            app.pMbEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.pMbEditField.Value = 0.52;
            app.Button_2 = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '确认';
            app.Label_3 = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.Label_3.Text = '爆炸概率';
            app.Explode_Prob = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.Explode_Prob.Value = 1e-06;
            app.EditField_2Label = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.EditField_2Label.Text = '置信水平';
            app.Trust_value = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.Trust_value.Value = 0.9999;
            app.EditField_3Label = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.EditField_3Label.Text = '安全设计值';
            app.Design_Safety_Val = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.Design_Safety_Val.Value = 26;
            app.Confrim_Prob = uibutton(app.BloodPressureAnalyisisUIFigure, 'state');
            app.Confrim_Prob.ValueChangedFcn = createCallbackFcn(app, @Confrim_ProbValueChanged, true);
            app.Confrim_Prob.Text = '确认';
            app.Result_plot = uiaxes(app.BloodPressureAnalyisisUIFigure);
            title(app.Result_plot, 'Title')
            xlabel(app.Result_plot, 'X')
            ylabel(app.Result_plot, 'Y')
            zlabel(app.Result_plot, 'Z')
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableDataAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end