classdef qmu_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ZJButton                  matlab.ui.control.Button
        ZJUITable                 matlab.ui.control.Table
        VaildEditField            matlab.ui.control.EditField
        MCFilePathEditField       matlab.ui.control.EditField
        FilePathEditField_2Label  matlab.ui.control.Label
        ZJFilePathEditField       matlab.ui.control.EditField
        MCUITable                 matlab.ui.control.Table
        MCButton                  matlab.ui.control.Button
        ZJ_QLabel                 matlab.ui.control.Label
        ZJ_QEditField             matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        rouEditField              matlab.ui.control.EditField
        MEditFieldLabel           matlab.ui.control.Label
        MEditField                matlab.ui.control.EditField
        bEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.EditField
        Label_3                   matlab.ui.control.Label
        MCNoteEditField           matlab.ui.control.EditField
        GEditField_2Label         matlab.ui.control.Label
        GEditField                matlab.ui.control.NumericEditField
        HEditField_2Label         matlab.ui.control.Label
        HEditField                matlab.ui.control.NumericEditField
        Label_5                   matlab.ui.control.Label
        UpEditField               matlab.ui.control.NumericEditField
        QButton                   matlab.ui.control.Button
        Label_6                   matlab.ui.control.Label
        X0EditField               matlab.ui.control.NumericEditField
        PlotButton                matlab.ui.control.Button
        Label_7                   matlab.ui.control.Label
        MClimEditField            matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        MCbetweenEditField        matlab.ui.control.EditField
        Label_9                   matlab.ui.control.Label
        MCalphaEditField          matlab.ui.control.NumericEditField
        Label_10                  matlab.ui.control.Label
        P0EditField               matlab.ui.control.EditField
        QButton2                  matlab.ui.control.Button
        MC_QEditFieldLabel        matlab.ui.control.Label
        MC_QEditField             matlab.ui.control.EditField
        Label_11                  matlab.ui.control.Label
        NoteEditField             matlab.ui.control.EditField
        Label_12                  matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZJButton
        function ZJButtonPushed(app, event)
              % 按下导入撞击数据按钮
            
%             输入excel的绝对路径
            xls_dir = app.ZJFilePathEditField.Value;
            
%             xls_dir = 'C:\Users\姚方浩\Desktop\QMU_app\qmu_app\zj_example.xlsx';
            data_example = xlsread(xls_dir, 'sheet1');

            % 比较v和m大小。判定n取vi还是mi
            [Ni_Stimulus_Xi,~] = get_Ni_Stimulus_Xi(data_example);
            
            Stimulus_Xi = data_example(:,1); %读取刺激量xi
            
            % 获取中位数X0 以及台阶数矩阵,步长d
            [stage_mitrix_i,~,~] =...
                get_stage_mitrix_and_X0(Stimulus_Xi);
            
            % 计算A B M b 的模块
            nABMb = calculate_parameter_n_A_B_M_b(Ni_Stimulus_Xi,stage_mitrix_i);
            parameter_n = nABMb.parameter_n;
            parameter_A = nABMb.parameter_A;
            parameter_B = nABMb.parameter_B;
            parameter_M = nABMb.parameter_M;
            parameter_little_b = nABMb.parameter_little_b;      
            
            %数据有效性判定 1有效 0 无效
            data_validity = check_for_data_validity(parameter_M);
            if data_validity == 1
                s = '数据有效，请查表输入G,H';
            else 
                s = '数据无效，M<0.25，请重新导入';
            end
            
            % 显示有效性结果
            app.VaildEditField.Value = s;
            
            app.NoteEditField.Value = 'G,H,置信水平,撞击安全设计值需自行输入';
            
            % 表格数据显示到界面上
            app.ZJUITable.Data = data_example;
            
%---------------------------------------------------------------------            
            
            % 计算ρ 
            parameter_M = roundn(parameter_M, -2); %保留两位小数
            if (parameter_M > 0.3)
                parameter_rou = 1.62 * ( parameter_M + 0.029 );
            else
                s = 'M<0.3, 请查表ρ(M,b)，自行输入ρ值！';
                app.NoteEditField.Value = s;
                % 自行给app.rouEditField.Value赋值
                parameter_rou = app.rouEditField.Value;
            end

            % 显示计算数据
            app.MEditField.Value = num2str(parameter_M);
            app.bEditField.Value = num2str(parameter_little_b);
            app.rouEditField.Value = num2str(parameter_rou);
         
        end

        % Button pushed function: MCButton
        function MCButtonPushed(app, event)
           % 按下导入摩擦数据按钮
            
%             输入excel的绝对路径
            xls_dir2 = app.MCFilePathEditField.Value;
            
%             xls_dir2 = 'C:\Users\姚方浩\Desktop\QMU_app\qmu_app\mc_example.xlsx';
            mc_data = xlsread(xls_dir2, 'sheet1');
            
            data_col = length(mc_data)/5;
            data_example2_matrix = reshape(mc_data, data_col, 5 ); %美化界面，五列展示
            
              % 表格数据显示到界面上
            app.MCUITable.Data = data_example2_matrix;
            
            app.MCNoteEditField.Value = '置信水平,摩擦安全设计值需自行输入';
%---------------------------------------------------------------------  

            %相关参数设置
            zhixindu = app.MCalphaEditField.Value;
            mc_th = app.MClimEditField.Value;
%             zhixindu = 0.95; %置信度
%             mc_th = 0.65; %安全性设计值

            % 计算试验数据的摩擦感度p
            explore_num = length(find(mc_data == 1));
            mc_prob = explore_num/length(mc_data);

            % 估计p的置信区间
            % 本质是一元二次方程
            mc_Z = get_Up((1-zhixindu) /2);% Za/2
            f_a = length(mc_data) +  mc_Z^2;
            f_b = -(2*length(mc_data)*mc_prob + mc_Z^2);
            f_c = length(mc_data)* (mc_prob^2);
            f_delta = sqrt(f_b^2 - 4*f_a*f_c);
            mc_p1ow = (-f_b - f_delta) / (2*f_a); %p2
            mc_phigh = (-f_b + f_delta) / (2*f_a); %p1
            mc_between = [mc_p1ow, mc_phigh];
            
            % 显示p和区间 
            app.P0EditField.Value = num2str(mc_prob);
            app.MCbetweenEditField.Value = num2str(mc_between);
            
        end

        % Button pushed function: QButton
        function QButtonPushed(app, event)
            
            %按下按钮 计算撞击Q值

            data_example = xlsread(app.ZJFilePathEditField.Value, 'sheet1');

            % 比较v和m大小。判定n取vi还是mi
            [Ni_Stimulus_Xi,explode_or_unexplode_data_flag] = get_Ni_Stimulus_Xi(data_example);
            
            Stimulus_Xi = data_example(:,1); %读取刺激量xi
            
            % 获取中位数X0 以及台阶数矩阵,步长d
            [stage_mitrix_i,Stimulus_median_X0,parameter_Step_sized] =...
                get_stage_mitrix_and_X0(Stimulus_Xi);
            
            % 计算A B M b 的模块
            nABMb = calculate_parameter_n_A_B_M_b(Ni_Stimulus_Xi,stage_mitrix_i);
            parameter_n = nABMb.parameter_n;
            parameter_A = nABMb.parameter_A;
            parameter_B = nABMb.parameter_B;
            parameter_M = nABMb.parameter_M;
            parameter_little_b = nABMb.parameter_little_b;      
            
%             %数据有效性判定 1有效 0 无效
%             data_validity = check_for_data_validity(parameter_M);
%             if data_validity == 1
%                 s = '数据有效，请查表输入G,H';
%             else 
%                 s = '数据无效，M<0.25，请重新导入';
%             end
%             
%             % 显示有效性结果
%             app.VaildEditField.Value = s;
%             
%             app.MCNoteEditField.Value = 'G,H,置信水平,安全设计值需自行输入';
%             
%             % 表格数据显示到界面上
%             app.ZJUITable.Data = data_example;
            
%---------------------------------------------------------------------            
            
            % 计算ρ 
            parameter_M = roundn(parameter_M, -2); %保留两位小数
            if (parameter_M > 0.3)
                parameter_rou = 1.62 * ( parameter_M + 0.029 );
            else
                s = 'M<0.3, 请查表ρ(M,b)，自行输入ρ值！';
                app.MCNoteEditField.Value = s;
                % 自行给app.rouEditField.Value赋值
                parameter_rou = app.rouEditField.Value;
            end

            % 显示计算数据
%             app.MEditField.Value = num2str(parameter_M);
%             app.bEditField.Value = num2str(parameter_little_b);
%             app.rouEditField.Value = num2str(parameter_rou);
            
            % 计算mu-hat
            parameter_miu = calculate_parameter_miu(...
                Stimulus_median_X0,explode_or_unexplode_data_flag,...
                parameter_A, parameter_n, parameter_Step_sized);            
            
            % 计算sigma-hat
            Std_Dev_sigma = calculate_Std_Dev_sigma(parameter_rou,parameter_Step_sized);  
         
            % 由自行输入的G H 
            % 计算sigma_mean sigma_variance
            parameter_G = app.GEditField.Value;
            parameter_H = app.HEditField.Value;
            sigma_mean = parameter_G * Std_Dev_sigma / sqrt(parameter_n);
            sigma_variance = parameter_H * Std_Dev_sigma / sqrt(parameter_n);

            % 由自行输入的置信水平
            prob = app.UpEditField.Value;
            % 计算sigma_Xp
            mu = 0;
            sigma = 1;
            pd = makedist('Normal','mu',mu,'sigma',sigma);
            p = 1 - prob;
            Up = icdf(pd,p);
%             p = 0.0001;
            sigma_explode_prob_Xp = sqrt(sigma_mean^2 + (Up * sigma_variance)^2 );
         
            % 计算置信区间（上下限）
%             explode_prob_Xp_QuantileU = ...
%                 calculate_explode_prob_Xp_QuantileU(explode_prob_Xp,Confidence_level,sigma_explode_prob_Xp);
            %撞击感度取置信下限
            % 计算M=Xth-X0
            X_th = parameter_miu - abs(Up) * Std_Dev_sigma;
            X_zero = app.X0EditField.Value;
            ZJ_M = X_th - X_zero;
            
            % 计算U=Xth-Xpl
            X_pl = X_th - abs(Up) * sigma_explode_prob_Xp;
            ZJ_U = X_th - X_pl; 
            
            % 计算Q
            ZJ_Q = ZJ_M / ZJ_U;
%---------------------------------------------------------------------    
            % 显示计算数据
            app.ZJ_QEditField.Value = num2str(ZJ_Q);
            
        end

        % Button pushed function: QButton2
        function QButton2Pushed(app, event)
            %按下按钮 计算摩擦Q值
           % 按下导入摩擦数据按钮
            
%             输入excel的绝对路径
            xls_dir2 = app.MCFilePathEditField.Value;
            
            mc_data = xlsread(xls_dir2, 'sheet1');
            
            data_col = length(mc_data)/5;
            data_example2_matrix = reshape(mc_data, data_col, 5 ); %美化界面，五列展示
            
              % 表格数据显示到界面上
            app.MCUITable.Data = data_example2_matrix;
            
            app.MCNoteEditField.Value = '置信水平,摩擦安全设计值需自行输入';
%---------------------------------------------------------------------  

            %相关参数设置
            zhixindu = app.MCalphaEditField.Value;
            mc_th = app.MClimEditField.Value;
%             zhixindu = 0.95; %置信度
%             mc_th = 0.65; %安全性设计值

            % 计算试验数据的摩擦感度p
            explore_num = length(find(mc_data == 1));
            mc_prob = explore_num/length(mc_data);

            % 估计p的置信区间
            % 本质是一元二次方程
            mc_Z = get_Up((1-zhixindu) /2);% Za/2
            f_a = length(mc_data) +  mc_Z^2;
            f_b = -(2*length(mc_data)*mc_prob + mc_Z^2);
            f_c = length(mc_data)* (mc_prob^2);
            f_delta = sqrt(f_b^2 - 4*f_a*f_c);
            mc_p1ow = (-f_b - f_delta) / (2*f_a); %p2
            mc_phigh = (-f_b + f_delta) / (2*f_a); %p1
            mc_between = [mc_p1ow, mc_phigh];
            
            % 显示p和区间 
            app.P0EditField.Value = num2str(mc_prob);
            app.MCbetweenEditField.Value = num2str(mc_between);
            
            % B类不确定度评定
            % 计算sigma_miu
            prob_a = (mc_phigh - mc_p1ow) / 2;
            mc_v = length(mc_data)-1;
            mc_t = tinv(zhixindu, mc_v); %计算t值
            mc_ux = prob_a / mc_t;
            % 计算sigma_sigma
            mc_sigmasigma = mc_ux/sqrt(2* mc_v);
            % 计算合成sigma_plow
            mc_sigmaplow = sqrt(mc_ux^2 + (mc_t*mc_sigmasigma)^2);
            
            % 计算M
            MC_M = mc_p1ow - mc_th;
            
            % 计算U
            MC_U = abs(get_Up(zhixindu)) * mc_sigmaplow;
            
            % 计算Q
            MC_Q = MC_M / MC_U;

%---------------------------------------------------------------------    
            % 显示计算数据
            app.MC_QEditField.Value = num2str(MC_Q);
                        
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % 撞击和摩擦Q值可视化 
            % 图像、雷达图

            data_example = xlsread(app.ZJFilePathEditField.Value, 'sheet1');
            [Ni_Stimulus_Xi,explode_or_unexplode_data_flag] = get_Ni_Stimulus_Xi(data_example);
            Stimulus_Xi = data_example(:,1); 
            [stage_mitrix_i,Stimulus_median_X0,parameter_Step_sized] =...
                get_stage_mitrix_and_X0(Stimulus_Xi);
            nABMb = calculate_parameter_n_A_B_M_b(Ni_Stimulus_Xi,stage_mitrix_i);
            parameter_n = nABMb.parameter_n;
            parameter_A = nABMb.parameter_A;
            parameter_B = nABMb.parameter_B;
            parameter_M = nABMb.parameter_M;
            parameter_little_b = nABMb.parameter_little_b;               
            parameter_M = roundn(parameter_M, -2); 
            if (parameter_M > 0.3)
                parameter_rou = 1.62 * ( parameter_M + 0.029 );
            else
                s = 'M<0.3, 请查表ρ(M,b)，自行输入ρ值！';
                app.MCNoteEditField.Value = s;
                parameter_rou = app.rouEditField.Value;
            end
            app.MEditField.Value = num2str(parameter_M);
            app.bEditField.Value = num2str(parameter_little_b);
            app.rouEditField.Value = num2str(parameter_rou);
            parameter_miu = calculate_parameter_miu(...
                Stimulus_median_X0,explode_or_unexplode_data_flag,...
                parameter_A, parameter_n, parameter_Step_sized);            
            Std_Dev_sigma = calculate_Std_Dev_sigma(parameter_rou,parameter_Step_sized);  
            parameter_G = app.GEditField.Value;
            parameter_H = app.HEditField.Value;
            sigma_mean = parameter_G * Std_Dev_sigma / sqrt(parameter_n);
            sigma_variance = parameter_H * Std_Dev_sigma / sqrt(parameter_n);
            prob = app.UpEditField.Value;
            mu = 0;
            sigma = 1;
            pd = makedist('Normal','mu',mu,'sigma',sigma);
            p = 1 - prob;
            Up = icdf(pd,p);    %             p = 0.0001;
            sigma_explode_prob_Xp = sqrt(sigma_mean^2 + (Up * sigma_variance)^2 );
            X_th = parameter_miu - abs(Up) * Std_Dev_sigma;
            X_zero = app.X0EditField.Value;
            ZJ_M = X_th - X_zero;
            X_pl = X_th - abs(Up) * sigma_explode_prob_Xp;
            ZJ_U = X_th - X_pl; 
            ZJ_Q = ZJ_M / ZJ_U;
            xls_dir2 = app.MCFilePathEditField.Value;
            mc_data = xlsread(xls_dir2, 'sheet1');
            data_col = length(mc_data)/5;
            data_example2_matrix = reshape(mc_data, data_col, 5 ); 
            app.MCUITable.Data = data_example2_matrix;
            app.MCNoteEditField.Value = '置信水平,摩擦安全设计值需自行输入';
            zhixindu = app.MCalphaEditField.Value;
            mc_th = app.MClimEditField.Value;
            explore_num = length(find(mc_data == 1));
            mc_prob = explore_num/length(mc_data);
            mc_Z = get_Up((1-zhixindu) /2);
            f_a = length(mc_data) +  mc_Z^2;
            f_b = -(2*length(mc_data)*mc_prob + mc_Z^2);
            f_c = length(mc_data)* (mc_prob^2);
            f_delta = sqrt(f_b^2 - 4*f_a*f_c);
            mc_p1ow = (-f_b - f_delta) / (2*f_a); 
            mc_phigh = (-f_b + f_delta) / (2*f_a); 
            mc_between = [mc_p1ow, mc_phigh];
            app.P0EditField.Value = num2str(mc_prob);
            app.MCbetweenEditField.Value = num2str(mc_between);
            prob_a = (mc_phigh - mc_p1ow) / 2;
            mc_v = length(mc_data)-1;
            mc_t = tinv(zhixindu, mc_v);
            mc_ux = prob_a / mc_t;
            mc_sigmasigma = mc_ux/sqrt(2* mc_v);
            mc_sigmaplow = sqrt(mc_ux^2 + (mc_t*mc_sigmasigma)^2);
            MC_M = mc_p1ow - mc_th;
            MC_U = abs(get_Up(zhixindu)) * mc_sigmaplow;
            MC_Q = MC_M / MC_U;  
         
%---------------------------------------------------------------------------
            % 显示Q值的图像
            ax = app.UIAxes;
            ax.XLim = [0 3];
            ax.YLim = [0 3];
%             x = [0 1 1 0];
%             y = [0 0 1 1];
%             x = [1 3 3 1];
%             y = [1 1 3 3];
            x = [0 3 3 1 1 0];
            y = [0 0 1 1 3 3];

            patch(ax,x,y,'red');
            hold(ax,'on');
            plot(ax, ZJ_Q, MC_Q, '+',...
                'MarkerFaceColor', 'g',...
                'MarkerSize',10);
            
%---------------------------------------------------------------------------
            % 显示雷达图
            % 更新至2022.1.25 待修改
            D1 = [ZJ_Q, MC_Q, 0, 0, 0];
            D2 = [1, 1, 0, 0, 0];
            
            % 下拉框选择模式
            value = app.DropDown.Value;
            switch value
                case '雷达图'
                    lim = [0,3;0,3;0,3;0,3;0,3];
                    labels = {'撞击', '摩擦', '未定义', '未定义', '未定义'};
%                     legendlables = {'test','threshold'};
                    Draw_radar(D1,lim,labels);
                    hold on;
                    Draw_radar(D2,lim,labels);
                case '蜘蛛网图'
                    P=[D1; D2];
                    spider_plot(P,...
                        'AxesLabels', {'撞击', '摩擦', '未定义', '未定义', '未定义'},...
                        'AxesLimits', [0, 0, 0, 0, 0; 3, 3, 3, 3, 3],... % [min axes limits; max axes limits]
                        'AxesPrecision', [1, 1, 1, 1, 1],...
                        'AxesInterval', 2,...
                        'FillOption', {'on', 'off'},...
                        'FillTransparency', [0.2, 0.1] );
                    legend_str = {'试验感度Q线', '置信阈值线'};
                    legend(legend_str, 'Location', 'northeast');           
            end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ZJButton = uibutton(app.UIFigure, 'push');
            app.ZJButton.ButtonPushedFcn = createCallbackFcn(app, @ZJButtonPushed, true);
            app.ZJButton.Text = '导入撞击数据';
            app.ZJUITable = uitable(app.UIFigure);
            app.ZJUITable.ColumnName = {'Xi'; 'Vi'; 'Mi'; 'Sum_Vi_Mi'};
            app.ZJUITable.RowName = {};
            app.VaildEditField = uieditfield(app.UIFigure, 'text');
            app.VaildEditField.Tooltip = {'判定数据是否有效'};
            app.MCFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.MCFilePathEditField.Tooltip = {'输入撞击试验excel文件的完整路径'};
            app.MCFilePathEditField.Value = 'C:\Users\姚方浩\Desktop\QMU_app\qmu_app\mc_example.xlsx';
            app.FilePathEditField_2Label = uilabel(app.UIFigure);
            app.FilePathEditField_2Label.Text = 'File Path';
            app.ZJFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ZJFilePathEditField.Tooltip = {'输入撞击试验excel文件的完整路径'};
            app.ZJFilePathEditField.Value = 'C:\Users\姚方浩\Desktop\QMU_app\qmu_app\zj_example.xlsx';
            app.MCUITable = uitable(app.UIFigure);
            app.MCUITable.ColumnName = {'响应结果'; ''; ''; ''};
            app.MCUITable.RowName = {};
            app.MCButton = uibutton(app.UIFigure, 'push');
            app.MCButton.ButtonPushedFcn = createCallbackFcn(app, @MCButtonPushed, true);
            app.MCButton.Text = '导入摩擦数据';
            app.ZJ_QLabel = uilabel(app.UIFigure);
            app.ZJ_QLabel.Text = 'ZJ_Q:';
            app.ZJ_QEditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ρ';
            app.rouEditField = uieditfield(app.UIFigure, 'text');
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '提示：';
            app.MCNoteEditField = uieditfield(app.UIFigure, 'text');
            app.GEditField_2Label = uilabel(app.UIFigure);
            app.GEditField_2Label.Text = 'G';
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.GEditField.Value = 1.031;
            app.HEditField_2Label = uilabel(app.UIFigure);
            app.HEditField_2Label.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.Value = 1.325;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '置信水平 ';
            app.UpEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpEditField.Value = 0.9999;
            app.QButton = uibutton(app.UIFigure, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.QButton.Text = '计算撞击Q值';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '撞击安全设计值';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditField.Value = 0.7;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'点击按钮会生成雷达图和蜘蛛图'};
            app.PlotButton.Text = '生成雷达图';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '摩擦安全设计值';
            app.MClimEditField = uieditfield(app.UIFigure, 'numeric');
            app.MClimEditField.Value = 0.55;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '感度值置信区间';
            app.MCbetweenEditField = uieditfield(app.UIFigure, 'text');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '置信水平';
            app.MCalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MCalphaEditField.Value = 0.95;
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '试验感度值';
            app.P0EditField = uieditfield(app.UIFigure, 'text');
            app.QButton2 = uibutton(app.UIFigure, 'push');
            app.QButton2.ButtonPushedFcn = createCallbackFcn(app, @QButton2Pushed, true);
            app.QButton2.Text = '计算摩擦Q值';
            app.MC_QEditFieldLabel = uilabel(app.UIFigure);
            app.MC_QEditFieldLabel.Text = 'MC_Q';
            app.MC_QEditField = uieditfield(app.UIFigure, 'text');
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '提示：';
            app.NoteEditField = uieditfield(app.UIFigure, 'text');
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '选择模式：';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'雷达图', '蜘蛛网图'};
            app.DropDown.Value = '雷达图';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '融合预测Q值')
            xlabel(app.UIAxes, '撞击')
            ylabel(app.UIAxes, '摩擦')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            app.UIAxes.YTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qmu_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end